classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % System ...
        Tab                    matlab.ui.container.Tab            % System ...
        LabelDropDown          matlab.ui.control.Label            % Channel...
        ChType                 matlab.ui.control.DropDown         % No chan...
        LabelListBox           matlab.ui.control.Label            % Bandwidths
        ListBW                 matlab.ui.control.ListBox          % 15000 (10)
        Add                    matlab.ui.control.Button           % Add
        Remove                 matlab.ui.control.Button           % Remove
        Panel                  matlab.ui.container.Panel          % Panel
        LabelNumericEditField  matlab.ui.control.Label            % BW
        FieldBW                matlab.ui.control.NumericEditField % [1 Inf]
        Add2                   matlab.ui.control.Button           % Add
        Label                  matlab.ui.control.Label            % Number ...
        FieldNumCar            matlab.ui.control.NumericEditField % [1 Inf]
        RBGraph                matlab.ui.control.UIAxes           % Resourc...
        LabelNumericEditField2 matlab.ui.control.Label            % CP dura...
        CP                     matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % FFT sam...
        N                      matlab.ui.control.NumericEditField % [1 Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % SNR
        SNR                    matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % dt
        dt                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % Omega
        Omega                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelListBox2          matlab.ui.control.Label            % h
        h                      matlab.ui.control.ListBox          % 1, 0.9,...
        MultiCheckBox          matlab.ui.control.CheckBox         % Manuel ...
        Add3                   matlab.ui.control.Button           % Add
        Clear                  matlab.ui.control.Button           % Clear
        hValue                 matlab.ui.control.NumericEditField % [-Inf Inf]
        Tab2                   matlab.ui.container.Tab            % Graphs
        Simulate               matlab.ui.control.Button           % Simulate
        LabelNumericEditField7 matlab.ui.control.Label            % Number ...
        NumberOfSymbols        matlab.ui.control.NumericEditField % [1 Inf]
        LabelEditField         matlab.ui.control.Label            % BER
        BER                    matlab.ui.control.EditField        % 0
        ReceviedGraph          matlab.ui.control.UIAxes           % Receive...
        LabelNumericEditField8 matlab.ui.control.Label            % Edit Field
        BERTheory              matlab.ui.control.NumericEditField % [-Inf Inf]
        Tab3                   matlab.ui.container.Tab            % BER Sweep
        SweepGraph             matlab.ui.control.UIAxes           % BER
        Sweep                  matlab.ui.control.Button           % Sweep
        LabelNumericEditField9 matlab.ui.control.Label            % From EbN0
        FromEbN0               matlab.ui.control.NumericEditField % [-Inf Inf]
        Label2                 matlab.ui.control.Label            % To EbN0
        ToEbN0                 matlab.ui.control.NumericEditField % [-Inf Inf]
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Position = [2 0 795 630];
            app.Panel.Visible = 'off';
            app.MultiCheckBox.Visible = 'off';
            app.dt.Visible = 'off';
            app.LabelNumericEditField5.Visible = 'off';
            app.Omega.Visible = 'off';
            app.LabelNumericEditField6.Visible = 'off';
            app.h.Visible = 'off';
            app.LabelListBox2.Visible = 'off';
            app.Add3.Visible = 'off';
            app.Clear.Visible = 'off';
            app.hValue.Visible = 'off';
            BWUpdate(app);
        end

        % Add button pushed function
        function AddPushed(app)
            app.Panel.Visible = 'on';
        end

        % Remove button pushed function
        function RemovePushed(app)


            content = app.ListBW.Items;
            n = 1;
            
            for k = 1:length(content);
                if ~strcmp(content(k), num2str(app.ListBW.Value))
                    new_content{n} = content{k};
                    n = n + 1;
                end
            end
            if exist('new_content')
                app.ListBW.Items = new_content;
                BWUpdate(app);
            else
                app.ListBW.Items = {};
                cla(app.RBGraph);
            end
            
        end

        % Add2 button pushed function
        function Add2Pushed(app)
            if app.FieldBW.Value > 0
                app.ListBW.Items{length(app.ListBW.Items)+1} = strcat(num2str(app.FieldBW.Value),' (',num2str(app.FieldNumCar.Value),+')');
                app.Panel.Visible = 'off';
            else
                warning('BW is invalid');
            end
            BWUpdate(app);
        end

        % ListBW value changed function
        function BWUpdate(app)
            cla(app.RBGraph);
            list = app.ListBW.Items;
            data = zeros(length(list),2);
            for n = 1:length(list)
                strings = strsplit(list{n},' ');
                data(n,1) = str2double(strings(1));
                numCarriers = char(strings(2));
                data(n,2) = str2double(numCarriers(2:end-1));
            end
            s = size(data);
            new_data = repmat(data(1,1),1,data(1,2));
            for n=2:s(1)
                new_data = [new_data repmat(data(n,1),1,data(n,2))];
            end
            T = 1/min(new_data);
            for n = 1:length(new_data)
                for k = 0:0.01:1
                    y = (1-k)*sum(new_data(1:n-1))+k*sum(new_data(1:n));
                    if mod(n,2)==0
                        line(app.RBGraph, [0 T], [y y])
                    else
                        line(app.RBGraph, [0 T], [y y],'color','g')
                    end
                end
                x = 0:1/new_data(n):T;
                for k = 1:length(x)
                    line(app.RBGraph, [x(k) x(k)], [sum(new_data(1:n-1)) sum(new_data(1:n))],'color','k')
                end
            end
            
                %plot(app.RBGraph,1:10,1:10);
        end

        % ChType value changed function
        function TypeChange(app, event)
            value = app.ChType.Value;
            if strcmp(value,'Rayleigh multipath')
                app.MultiCheckBox.Visible = 'on';
                app.dt.Visible = 'on';
                app.LabelNumericEditField5.Visible = 'on';
                MultiChg(app);
            elseif strcmp(value,'Rayleigh no multipath')
                app.MultiCheckBox.Visible = 'off';
                app.dt.Visible = 'off';
                app.LabelNumericEditField5.Visible = 'off';
                app.Omega.Visible = 'off';
                app.LabelNumericEditField6.Visible = 'off';
                app.h.Visible = 'off';
                app.LabelListBox2.Visible = 'off';
                app.Add3.Visible = 'off';
                app.Clear.Visible = 'off';
                app.hValue.Visible = 'off';
            elseif strcmp(value,'No channel')
                app.MultiCheckBox.Visible = 'off';
                app.dt.Visible = 'off';
                app.LabelNumericEditField5.Visible = 'off';
                app.Omega.Visible = 'off';
                app.LabelNumericEditField6.Visible = 'off';
                app.h.Visible = 'off';
                app.LabelListBox2.Visible = 'off';
                app.Add3.Visible = 'off';
                app.Clear.Visible = 'off';
                app.hValue.Visible = 'off';
            end
        end

        % MultiCheckBox value changed function
        function MultiChg(app)
            if app.MultiCheckBox.Value
                app.Omega.Visible = 'off';
                app.LabelNumericEditField6.Visible = 'off';
                app.h.Visible = 'on';
                app.LabelListBox2.Visible = 'on';
                app.Add3.Visible = 'on';
                app.Clear.Visible = 'on';
                app.hValue.Visible = 'on';
            else
                app.Omega.Visible = 'on';
                app.LabelNumericEditField6.Visible = 'on';
                app.h.Visible = 'off';
                app.LabelListBox2.Visible = 'off';  
                app.Add3.Visible = 'off';
                app.Clear.Visible = 'off';
                app.hValue.Visible = 'off';
            end
        end

        % Add3 button pushed function
        function Add3ButtonPushed(app)
            app.h.Items{length(app.h.Items)+1} = num2str(app.hValue.Value);
        end

        % Clear button pushed function
        function ClearButtonPushed(app)
            app.h.Items = {};
        end

        % Simulate button pushed function
        function SimulateButtonPushed(app)
           % app.Simulate.Enable = 'off';
            list = app.ListBW.Items;
            data = zeros(length(list),2);
            for n = 1:length(list)
                strings = strsplit(list{n},' ');
                data(n,1) = str2double(strings(1));
                numCarriers = char(strings(2));
                data(n,2) = str2double(numCarriers(2:end-1));
            end
            s = size(data);
            new_data = repmat(data(1,1),1,data(1,2));
            for n=2:s(1)
                new_data = [new_data repmat(data(n,1),1,data(n,2))];
            end

            system.fDev = new_data;
           % system.fc = [0 10000 20000 30000 40000 60000 80000 100000 120000 140000];
            system.N = app.N.Value;
            system.CP_dur = app.CP.Value;
            
            
            channel.EbN0 = app.SNR.Value;
            channel.SNR =  app.SNR.Value;
            value = app.ChType.Value;
            if strcmp(value,'Rayleigh multipath')
                channel.type = 1;
                channel.dt = app.dt.Value; 
                if app.MultiCheckBox.Value
                    channel.h = app.h.Value;
                else
                    channel.omega = app.Omega.Value;
                end
            elseif strcmp(value,'Rayleigh no multipath')
                channel.type = 0;
            elseif strcmp(value,'No channel')
                channel.type = -1;
            end
            BPS = 2;
            scheme = 'QPSK';
            Bits = randi([0 1],BPS*app.NumberOfSymbols.Value*length(new_data),1);
            %Bits = repmat([1 1 0 1 1 0 0 0]',10,1);
            [symbols] = symbolGen(Bits, scheme);


            [RecSym] = Simulation_v1_3(system,channel,symbols);


            recBits = symbolDegen(RecSym,scheme);
            Err =  sum(xor(recBits,Bits));
            app.BER.Value = num2str(Err/length(Bits));
            
            cla(app.ReceviedGraph);
            plot(app.ReceviedGraph, real(RecSym), imag(RecSym),'o','LineWidth',0.5);
            
            Eb_No = channel.EbN0;
            app.BERTheory.Value = (0.5*erfc(sqrt(10.^(Eb_No/10))));
            
           % app.Simulate.Enable = 'on';
        end

        % Sweep button pushed function
        function SweepButtonPushed(app)
            list = app.ListBW.Items;
            data = zeros(length(list),2);
            for n = 1:length(list)
                strings = strsplit(list{n},' ');
                data(n,1) = str2double(strings(1));
                numCarriers = char(strings(2));
                data(n,2) = str2double(numCarriers(2:end-1));
            end
            s = size(data);
            new_data = repmat(data(1,1),1,data(1,2));
            for n=2:s(1)
                new_data = [new_data repmat(data(n,1),1,data(n,2))];
            end

            system.fDev = new_data;
           % system.fc = [0 10000 20000 30000 40000 60000 80000 100000 120000 140000];
            system.N = app.N.Value;
            system.CP_dur = app.CP.Value;
            
            value = app.ChType.Value;
            if strcmp(value,'Rayleigh multipath')
                channel.type = 1;
                channel.dt = app.dt.Value; 
                if app.MultiCheckBox.Value
                    channel.h = app.h.Value;
                else
                    channel.omega = app.Omega.Value;
                end
            elseif strcmp(value,'Rayleigh no multipath')
                channel.type = 0;
            elseif strcmp(value,'No channel')
                channel.type = -1;
            end
            BPS = 2;
            scheme = 'QPSK';
            
            EbN0 = app.FromEbN0.Value:0.5:app.ToEbN0.Value;
            for n = 1:length(EbN0)
                Err = 0;
                NumBits = 0;
                channel.EbN0 = EbN0(n);
                disp(EbN0(n));
                while Err < 100
                    NumBits = NumBits + BPS*100*length(new_data);
                    Bits = randi([0 1],BPS*100*length(new_data),1);
                    
                    [symbols] = symbolGen(Bits, scheme);
                    
                    [RecSym] = Simulation_v1_3(system,channel,symbols);
                    
                    recBits = symbolDegen(RecSym,scheme);
                    Err =  Err + sum(xor(recBits,Bits));
                end
                BERn(n) = Err/NumBits;
            end
            
            
            cla(app.SweepGraph);
            semilogy(app.SweepGraph, EbN0,BERn,'-x','LineWidth',0.5);
            hold(app.SweepGraph,'on');
            semilogy(app.SweepGraph, EbN0,(0.5*erfc(sqrt(10.^(EbN0/10)))),'-x','LineWidth',0.5);
            M = 4;
            semilogy(app.SweepGraph, EbN0,(2*(M-1)/(M*log2(M))*qfunc(sqrt(6*10.^(EbN0/10)*log2(M)/(M^2-1)))),'-x','LineWidth',0.5);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'System parameters';
            app.LabelDropDown = uilabel(app.Tab);
            app.LabelDropDown.Text = 'Channel type';
            app.ChType = uidropdown(app.Tab);
            app.ChType.Items = {'No channel', 'Rayleigh no multipath', 'Rayleigh multipath'};
            app.ChType.ValueChangedFcn = createCallbackFcn(app, @TypeChange, true);
            app.ChType.Value = 'No channel';
            app.LabelListBox = uilabel(app.Tab);
            app.LabelListBox.Text = 'Bandwidths';
            app.ListBW = uilistbox(app.Tab);
            app.ListBW.Items = {'15000 (10)'};
            app.ListBW.ValueChangedFcn = createCallbackFcn(app, @BWUpdate);
            app.ListBW.Value = '15000 (10)';
            app.Add = uibutton(app.Tab, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddPushed);
            app.Add.Text = 'Add';
            app.Remove = uibutton(app.Tab, 'push');
            app.Remove.ButtonPushedFcn = createCallbackFcn(app, @RemovePushed);
            app.Remove.Text = 'Remove';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'BW';
            app.FieldBW = uieditfield(app.Panel, 'numeric');
            app.FieldBW.Limits = [1 Inf];
            app.FieldBW.ValueDisplayFormat = '%.0f';
            app.FieldBW.Value = 15000;
            app.Add2 = uibutton(app.Panel, 'push');
            app.Add2.ButtonPushedFcn = createCallbackFcn(app, @Add2Pushed);
            app.Add2.Text = 'Add';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Number of carriers';
            app.FieldNumCar = uieditfield(app.Panel, 'numeric');
            app.FieldNumCar.Limits = [1 Inf];
            app.FieldNumCar.ValueDisplayFormat = '%.0f';
            app.FieldNumCar.Value = 10;
            app.RBGraph = uiaxes(app.Tab);
            title(app.RBGraph, 'Resource allocation');
            xlabel(app.RBGraph, 'Time');
            ylabel(app.RBGraph, 'Frequency');
            app.LabelNumericEditField2 = uilabel(app.Tab);
            app.LabelNumericEditField2.Text = 'CP duration';
            app.CP = uieditfield(app.Tab, 'numeric');
            app.CP.Limits = [0 Inf];
            app.CP.Value = 1;
            app.LabelNumericEditField3 = uilabel(app.Tab);
            app.LabelNumericEditField3.Text = 'FFT samples';
            app.N = uieditfield(app.Tab, 'numeric');
            app.N.Limits = [1 Inf];
            app.N.Value = 1024;
            app.LabelNumericEditField4 = uilabel(app.Tab);
            app.LabelNumericEditField4.Text = 'SNR';
            app.SNR = uieditfield(app.Tab, 'numeric');
            app.SNR.Value = 10;
            app.LabelNumericEditField5 = uilabel(app.Tab);
            app.LabelNumericEditField5.Text = 'dt';
            app.dt = uieditfield(app.Tab, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.Tab);
            app.LabelNumericEditField6.Text = 'Omega';
            app.Omega = uieditfield(app.Tab, 'numeric');
            app.LabelListBox2 = uilabel(app.Tab);
            app.LabelListBox2.Text = 'h';
            app.h = uilistbox(app.Tab);
            app.h.Items = {'1', '0.9', '0.8', '0.7'};
            app.h.Value = '1';
            app.MultiCheckBox = uicheckbox(app.Tab);
            app.MultiCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiChg);
            app.MultiCheckBox.Text = 'Manuel multipath';
            app.Add3 = uibutton(app.Tab, 'push');
            app.Add3.ButtonPushedFcn = createCallbackFcn(app, @Add3ButtonPushed);
            app.Add3.Text = 'Add';
            app.Clear = uibutton(app.Tab, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed);
            app.Clear.Text = 'Clear';
            app.hValue = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Graphs';
            app.Simulate = uibutton(app.Tab2, 'push');
            app.Simulate.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed);
            app.Simulate.Text = 'Simulate';
            app.LabelNumericEditField7 = uilabel(app.Tab2);
            app.LabelNumericEditField7.Text = 'Number of symbols';
            app.NumberOfSymbols = uieditfield(app.Tab2, 'numeric');
            app.NumberOfSymbols.Limits = [1 Inf];
            app.NumberOfSymbols.Value = 200;
            app.LabelEditField = uilabel(app.Tab2);
            app.LabelEditField.Text = 'BER';
            app.BER = uieditfield(app.Tab2, 'text');
            app.BER.Editable = 'off';
            app.BER.Value = '0';
            app.ReceviedGraph = uiaxes(app.Tab2);
            title(app.ReceviedGraph, 'Received Symbols');
            xlabel(app.ReceviedGraph, 'Real value');
            ylabel(app.ReceviedGraph, 'Imaginear value');
            app.LabelNumericEditField8 = uilabel(app.Tab2);
            app.LabelNumericEditField8.Text = 'Edit Field';
            app.BERTheory = uieditfield(app.Tab2, 'numeric');
            app.BERTheory.Editable = 'off';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'BER Sweep';
            app.SweepGraph = uiaxes(app.Tab3);
            title(app.SweepGraph, 'BER');
            xlabel(app.SweepGraph, 'EbN0');
            ylabel(app.SweepGraph, 'BER');
            app.Sweep = uibutton(app.Tab3, 'push');
            app.Sweep.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed);
            app.Sweep.Text = 'Sweep';
            app.LabelNumericEditField9 = uilabel(app.Tab3);
            app.LabelNumericEditField9.Text = 'From EbN0';
            app.FromEbN0 = uieditfield(app.Tab3, 'numeric');
            app.FromEbN0.Value = -5;
            app.Label2 = uilabel(app.Tab3);
            app.Label2.Text = 'To EbN0';
            app.ToEbN0 = uieditfield(app.Tab3, 'numeric');
            app.ToEbN0.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end